classdef covid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryLabel               matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TimePoints % measurement times (in days)
        ShowCases  % plot cases
        ShowDeaths % plot deaths
        Countries_list
        Places          Place
        CurrentCountry
        CurrentState
        DayAverage
        Type
        Option
    end
    
    methods (Access = private)
        
function PlotCurves(app)
            barcolor = [0 0 255]/255;
            linecolor = [255 0 0]/255;
            avr = app.DayAverage;
            tp = app.Type;
            cc = app.CurrentCountry;
            cs = app.CurrentState;
            op = app.Option;
            
            dname = tp;
            if isequal(tp,'Both')
                dname = 'Cases and Deaths';
            end
            app.UIAxes.Title.String = [op,' Numbers of ',dname ,' in ',cs,' State(Province) of ', cc, ' (',num2str(avr),'-day mean)'];
            
            Flag = [strcmp(cc,'All Countries'), strcmp(cs,'all'), strcmp(tp,{'Cases','Deaths','Both'}), strcmp(op,{'Cumulative','Daily'})];
            code = bin2dec(num2str(Flag));
            indexc = strcmp(app.Countries_list,cc);
            if sum(indexc) == 0
                indexs = [];
            else
                indexs = strcmp(app.Places(indexc).StateNameList, cs);
            end
            for i = 1:length(app.Countries_list)
                cdd(i,:) = app.Places(i).DailyCases;
                ddd(i,:) = app.Places(i).DailyDeaths;
                cdc(i,:) = app.Places(i).CumulativeCases;
                ddc(i,:) = app.Places(i).CumulativeDeaths;
            end
            [r,c] = size(cdc);
            cases = [];
            deaths = [];
            switch code
                case {5}
                    cases = movmean(app.Places(indexc).States(indexs).DailyCases, avr,'Endpoints','discard');
                    deaths = movmean(app.Places(indexc).States(indexs).DailyDeaths, avr,'Endpoints','discard');
                case {37}
                    cases = movmean(app.Places(indexc).DailyCases, avr,'Endpoints','discard');
                    deaths = movmean(app.Places(indexc).DailyDeaths, avr,'Endpoints','discard');
                case {69,101}
                    cases = movmean(sum(cdd,1), avr,'Endpoints','discard');
                    deaths = movmean(sum(ddd,1), avr,'Endpoints','discard');
                case {6}
                    cases = movmean(app.Places(indexc).States(indexs).CumulativeCases, avr,'Endpoints','discard');
                    deaths = movmean(app.Places(indexc).States(indexs).CumulativeDeaths, avr,'Endpoints','discard');
                case {38}
                    cases = movmean(app.Places(indexc).CumulativeCases, avr,'Endpoints','discard');
                    deaths = movmean(app.Places(indexc).CumulativeDeaths, avr,'Endpoints','discard');
                case {70,102}
                    cases = movmean(sum(cdc,1), avr,'Endpoints','discard');
                    deaths = movmean(sum(ddc,1), avr,'Endpoints','discard');
                case {9}
                    deaths = movmean(app.Places(indexc).States(indexs).DailyDeaths, avr,'Endpoints','discard');
                case {41}
                    deaths = movmean(app.Places(indexc).DailyDeaths, avr,'Endpoints','discard');
                case {73,105}
                    deaths = movmean(sum(ddd,1), avr,'Endpoints','discard');
                case {10}
                    deaths = movmean(app.Places(indexc).States(indexs).CumulativeDeaths, avr,'Endpoints','discard');
                case {42}
                    deaths = movmean(app.Places(indexc).CumulativeDeaths, avr,'Endpoints','discard');
                case {74,106}
                    deaths = movmean(sum(cdc,1), avr,'Endpoints','discard');
                case {17}
                    cases = movmean(app.Places(indexc).States(indexs).DailyCases, avr,'Endpoints','discard');
                case {49}
                    cases = movmean(app.Places(indexc).DailyCases, avr,'Endpoints','discard');
                case {81,113}
                    cases = movmean(sum(cdd,1), avr,'Endpoints','discard');
                case {18}
                    cases = movmean(app.Places(indexc).States(indexs).CumulativeCases, avr,'Endpoints','discard');
                case {50}
                    cases = movmean(app.Places(indexc).CumulativeCases, avr,'Endpoints','discard');
                case {82,114}
                    cases = movmean(sum(cdc,1), avr,'Endpoints','discard');
            end
            if ~isempty(cases) && ~isempty(deaths)
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes,cases, 0.5,'FaceColor',barcolor);
                app.UIAxes.YColor = barcolor;
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes,deaths,'LineWidth',2,'Color',linecolor);
                app.UIAxes.YColor = linecolor;
                app.UIAxes.XLim = [1,length(cases)];
            else
                if isempty(deaths)
                    yyaxis(app.UIAxes, 'left');
                    bar(app.UIAxes,cases, 0.5,'FaceColor',barcolor);
                    app.UIAxes.YColor = barcolor;
                    yyaxis(app.UIAxes, 'right');
                    cla(app.UIAxes);
                    app.UIAxes.XLim = [1,length(cases)];
                else
                    yyaxis(app.UIAxes, 'right');
                    plot(app.UIAxes,deaths,'LineWidth',2,'Color',linecolor);
                    app.UIAxes.YColor = linecolor;
                    yyaxis(app.UIAxes, 'left');
                    cla(app.UIAxes);
                    app.UIAxes.XLim = [1,length(deaths)];
                end
            end
            app.UIAxes.YLim = [0,inf];
            lx = length(app.UIAxes.XTick);
            ld = length(app.TimePoints);
            cut = ceil((avr-1)/2);
            app.UIAxes.XTickLabel = app.TimePoints(1+cut:ceil(ld/lx):end-cut);
            app.UIAxes.XTickLabelRotation = 60;
            app.UIAxes.FontWeight = 'bold';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("covid_data.mat");
            data = covid_data;
            app.TimePoints = data(1,3:end);
            app.Countries_list = unique(data(2:end,1));

            for i = 1:length(app.Countries_list)
                name = app.Countries_list{i};
                index = strcmp(name,data(:,1));
                app.Places(i) = Place(name, data(index,2), data(index,3:end));
            end

            app.CountryListBox.Items = [ 'All Countries'; app.Countries_list ];
            app.CurrentCountry = 'All Countries';
            app.StateorRegionListBox.Items = {'All'};
            app.CurrentState = 'All';
            app.Type = 'Cases';
            app.Option = 'Cumulative';
            app.DayAverage = 1;
            PlotCurves(app);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.CurrentCountry = app.CountryListBox.Value;
            
            indexc = strcmp(app.Countries_list,app.CurrentCountry);
            if sum(indexc) == 0
                app.StateorRegionListBox.Items = {'All'};
            else
                app.StateorRegionListBox.Items = ['All'; app.Places(indexc).StateNameList];
            end
            app.CurrentState = 'All';
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.Type = app.DataofPlotButtonGroup.SelectedObject.Text;
            PlotCurves(app);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.CurrentState = app.StateorRegionListBox.Value;
            PlotCurves(app);
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.DayAverage = round(event.Value);
            PlotCurves(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Daily Number of Cases and Deaths in')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {'Empty'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = 'Empty';
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = {'State or '; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.Items = {'Empty'};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = 'Empty';
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged '; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end